classdef TaqBalam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        EstablecerComunicacinTab  matlab.ui.container.Tab
        ConectarESP32Label        matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        BaudRateEditField         matlab.ui.control.EditField
        BaudRateEditFieldLabel    matlab.ui.control.Label
        PuertosListBox            matlab.ui.control.ListBox
        PuertosListBoxLabel       matlab.ui.control.Label
        ConectarButton            matlab.ui.control.Button
        PuertosButton             matlab.ui.control.Button
        LecturasTab               matlab.ui.container.Tab
        Label_4                   matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        LecturasSwitch            matlab.ui.control.Switch
        LecturasSwitchLabel       matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        ArtificialHorizon         Aero.ui.control.ArtificialHorizon
        RPMRPMIndicator           Aero.ui.control.RPMIndicator
        RPMRPMIndicatorLabel      matlab.ui.control.Label
        VelocidadCarroGauge       matlab.ui.control.Gauge
        VelocidadCarroGaugeLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        ConfiguracinTab           matlab.ui.container.Tab
    end


    properties (Access = public)
        baudrate;
        Fs;
        ports;
        serialdata;
        comconectado;
        RPM;
        VEL;
        roll;
        pitch;
        yaw;
        arreglodedatos ;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.baudrate;
            app.Fs;
            app.ports;
            app.serialdata;
            app.comconectado = 0;
            app.RPM;
            app.VEL;
            app.roll;
            app.pitch;
            app.yaw;
            app.arreglodedatos;

        end

        % Button pushed function: PuertosButton
        function PuertosButtonPushed(app, event)
            puertos = serialportlist; % Se obtienen los puertos disponibles, se pueden seleccionar
            app.PuertosListBox.Items = puertos;
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            if isempty(app.BaudRateEditField.Value)%% Si no se ha puesto el baudrate se dice y si si, se conecta al dispositivo de captura

                app.Label_3.Text = '*Falta llenar datos';
                app.Label_3.FontColor = [ 1.0 0.0 0.0];
            else
                if app.comconectado == 1 % Si ya estaba conectado, desconecta para volver a conectar
                    app.serialdata = 0;
                    s = 0;
                end

                app.Label_3.Text = '  ';
                s = serialport(app.PuertosListBox.Value,str2double(app.BaudRateEditField.Value));
                app.serialdata = s;
                s
                app.comconectado = 1;

            end
        end

        % Value changed function: LecturasSwitch
        function LecturasSwitchValueChanged(app, event)
            contador = 1;
            recibido = 0;
            dato = 0;
            comenzarrecibir = 0;
            

            value = app.LecturasSwitch.Value;
            if value == "Off"
                app.Lamp.Color = [1.00,0.00,0.00];
            elseif value == "On"
                app.Lamp.Color = [ 0.00,1.00,0.00];

                while value == "On" %% recibimos datos, lo descomponemos y lo almacenamos
                    dato = readline(app.serialdata);
                    dato


                    if dato == 'A'
                        %recibido = 0;
                        comenzarrecibir = 1;
                        dato = readline(app.serialdata);
                        contador = 1;

                    elseif dato == 'B'
                        comenzarrecibir = 0;
                        app.arreglodedatos = recibido;
                         

                    end

                    if comenzarrecibir == 1
                        recibido(contador) = str2double(dato);
                        contador = contador + 1;


                    end

                end

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EstablecerComunicacinTab = uitab(app.TabGroup);
            app.EstablecerComunicacinTab.Title = 'Establecer Comunicación';
            app.PuertosButton = uibutton(app.EstablecerComunicacinTab, 'push');
            app.PuertosButton.ButtonPushedFcn = createCallbackFcn(app, @PuertosButtonPushed, true);
            app.PuertosButton.Text = 'Puertos';
            app.ConectarButton = uibutton(app.EstablecerComunicacinTab, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.PuertosListBoxLabel = uilabel(app.EstablecerComunicacinTab);
            app.PuertosListBoxLabel.Text = 'Puertos';
            app.PuertosListBox = uilistbox(app.EstablecerComunicacinTab);
            app.PuertosListBox.Items = {};
            app.PuertosListBox.Value = {};
            app.BaudRateEditFieldLabel = uilabel(app.EstablecerComunicacinTab);
            app.BaudRateEditFieldLabel.Text = 'BaudRate';
            app.BaudRateEditField = uieditfield(app.EstablecerComunicacinTab, 'text');
            app.Label_3 = uilabel(app.EstablecerComunicacinTab);
            app.Label_3.Text = '';
            app.ConectarESP32Label = uilabel(app.EstablecerComunicacinTab);
            app.ConectarESP32Label.Text = 'Conectar ESP32';
            app.LecturasTab = uitab(app.TabGroup);
            app.LecturasTab.Title = 'Lecturas';
            app.UIAxes = uiaxes(app.LecturasTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.VelocidadCarroGaugeLabel = uilabel(app.LecturasTab);
            app.VelocidadCarroGaugeLabel.Text = 'Velocidad Carro';
            app.VelocidadCarroGauge = uigauge(app.LecturasTab, 'circular');
            app.RPMRPMIndicatorLabel = uilabel(app.LecturasTab);
            app.RPMRPMIndicatorLabel.Text = 'RPM';
            app.RPMRPMIndicator = uiaerorpm(app.LecturasTab);
            app.ArtificialHorizon = uiaerohorizon(app.LecturasTab);
            app.Label = uilabel(app.LecturasTab);
            app.Label.Text = '100';
            app.Label_2 = uilabel(app.LecturasTab);
            app.Label_2.Text = {'10000'; ''};
            app.LecturasSwitchLabel = uilabel(app.LecturasTab);
            app.LecturasSwitchLabel.Text = 'Lecturas';
            app.LecturasSwitch = uiswitch(app.LecturasTab, 'slider');
            app.LecturasSwitch.ValueChangedFcn = createCallbackFcn(app, @LecturasSwitchValueChanged, true);
            app.Lamp = uilamp(app.LecturasTab);
            app.Label_4 = uilabel(app.LecturasTab);
            app.Label_4.Text = '';
            app.ConfiguracinTab = uitab(app.TabGroup);
            app.ConfiguracinTab.Title = 'Configuración';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TaqBalam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end